classdef Cepstral_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CepestralAnalysisUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        SamplesSpinner             matlab.ui.control.Spinner
        SamplesSpinnerLabel        matlab.ui.control.Label
        ComplixplaneButtonGroup    matlab.ui.container.ButtonGroup
        ImaginaryButton            matlab.ui.control.RadioButton
        RealButton                 matlab.ui.control.RadioButton
        FrameIDSpinner             matlab.ui.control.Spinner
        FrameIDSpinnerLabel        matlab.ui.control.Label
        NormalizingSwitch          matlab.ui.control.Switch
        FrameshiftsSpinner         matlab.ui.control.Spinner
        FrameshiftsSpinnerLabel    matlab.ui.control.Label
        FramelengthsSpinner        matlab.ui.control.Spinner
        FramelengthsSpinnerLabel   matlab.ui.control.Label
        UpdateButton               matlab.ui.control.Button
        WindowingButtonGroup       matlab.ui.container.ButtonGroup
        BlackmanButton             matlab.ui.control.RadioButton
        HanningButton              matlab.ui.control.RadioButton
        HammingButton              matlab.ui.control.RadioButton
        NoneButton                 matlab.ui.control.RadioButton
        Label                      matlab.ui.control.Label
        FramesegmentaionLabel      matlab.ui.control.Label
        FilterSwitchLabel          matlab.ui.control.Label
        NormalizingSwitchLabel     matlab.ui.control.Label
        SmoothingSwitchLabel       matlab.ui.control.Label
        FilterSwitch               matlab.ui.control.Switch
        SmoothingSwitch            matlab.ui.control.Switch
        LoadfileButton             matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        PlotDropDown               matlab.ui.control.DropDown
        PlotDropDownLabel          matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fileName
        x     % signal
        fs    % sampling rate
        y
        z
        z_norm
        frame_length
        frame_shift
        frame_ID
        win_type
        com_plane
        frames_fft
        half_nfft
        f
        cepstrum
        extracted_frame_log
        extracted_frame_cepstrum
        t
        samples
    end
    
    methods (Access = private)
        
        function Compute(app)
            if isempty(app.x)
                errordlg("No file is chosen", "Fille Error")
            else
                % dividing the signal into frames
                frameLength = round(app.fs * app.frame_length);
                frameShift = round(app.fs * app.frame_shift);
                frames = buffer(app.z_norm, frameLength, frameLength - frameShift, 'nodelay');

                % Windowing the frames
                if strcmp(app.win_type, 'None')
                    framesWin = frames;
                elseif strcmp(app.win_type, 'Hamming')
                    win = hamming(frameLength);
                    framesWin = frames .* repmat(win, 1, size(frames, 2));
                elseif strcmp(app.win_type, 'Hanning')
                    win = hann(frameLength);
                    framesWin = frames .* repmat(win, 1, size(frames, 2));
                elseif strcmp(app.win_type, 'Blackman ')
                    win = blackman(frameLength);
                    framesWin = frames .* repmat(win, 1, size(frames, 2));
                else
                    framesWin = frames;
                end

                % fft
                nfft = 2^nextpow2(frameLength);
                app.frames_fft = abs(fft(framesWin, nfft)).^2;
                app.half_nfft = nfft / 2 + 1;
                app.f = linspace(0, app.fs/2,app.half_nfft);

                % Logarithmic Scalling
                frame_log = log10(app.frames_fft);

                % ifft
                if strcmp(app.com_plane, 'Real')
                    app.cepstrum = real(ifft(frame_log));
                elseif strcmp(app.com_plane, 'Imaginary')
                    app.cepstrum = imag(ifft(frame_log));
                else
                    app.cepstrum = real(ifft(frame_log));
                end

                % prepare date to the plot
                app.extracted_frame_log = app.frames_fft(:, app.frame_ID);
                app.extracted_frame_cepstrum = app.cepstrum(:, app.frame_ID);
                app.t = linspace(0,frameLength/app.fs, frameLength);

            end
        end
    
        function ResetAll(app)
            app.FilterSwitch.Value = 'Off';
            app.SmoothingSwitch.Value = 'Off';
            app.NormalizingSwitch.Value = 'Off';
            app.FrameIDSpinner.Value = 1;
            app.FramelengthsSpinner.Value = 0;
            app.FrameshiftsSpinner.Value = 0;
            app.PlotDropDown.Value = 'None';
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            app.ResetAll();
            
            % Loding the file to the app
            [app.fileName, filePath] = uigetfile({'*.wav;*.mat', 'WAV or MAT Files'}, 'Select File');
            fullPath = fullfile(filePath, app.fileName);
            if isequal(app.fileName, 0)
                errordlg("No file is chosen", "Fille Error")
                return
            end

            [~, ~, extension] = fileparts(app.fileName);
            
            % Check if the file is a .wav audio file
            if strcmp(extension, '.wav')
                [app.x, app.fs] = audioread(fullPath);
            % Check if the file is a .mat file
            elseif strcmpi(extension, '.mat')
                % errordlg('worked')
                tt = load(fullPath);
                app.x = tt.t;
                app.fs = app.samples;
            end
            
        end

        % Value changed function: FilterSwitch
        function FilterSwitchValueChanged(app, event)
            value = app.FilterSwitch.Value;
            % Apply a digital filter to remove noise
            if strcmp(value, 'on')
                b = fir1(50, 0.5);
                app.y = filter(b, 1, app.x);
            else
                app.y = app.x;
            end
        end

        % Value changed function: SmoothingSwitch
        function SmoothingSwitchValueChanged(app, event)
            value = app.SmoothingSwitch.Value;
            % Apply a moving average filter to smooth the signal
            if strcmp(value, 'on')
                app.z = smoothdata(app.y, 'movmean', 100);
            else
                app.z = app.y;
            end
        end

        % Value changed function: NormalizingSwitch
        function NormalizingSwitchValueChanged(app, event)
            value = app.NormalizingSwitch.Value;
            % Normalize the signal to the range [-1, 1]
            if strcmp(value, 'on')
                app.z_norm = normalize(app.z, 'range', [-1 1]);
            else
                app.z_norm = app.z;
            end
        end

        % Value changed function: FramelengthsSpinner
        function FramelengthsSpinnerValueChanged(app, event)
            value = app.FramelengthsSpinner.Value;
            app.frame_length = value;
        end

        % Value changed function: FrameshiftsSpinner
        function FrameshiftsSpinnerValueChanged(app, event)
            value = app.FrameshiftsSpinner.Value;
            app.frame_shift = value;
        end

        % Value changed function: FrameIDSpinner
        function FrameIDSpinnerValueChanged(app, event)
            value = app.FrameIDSpinner.Value;
            app.frame_ID = value;
        end

        % Selection changed function: WindowingButtonGroup
        function WindowingButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowingButtonGroup.SelectedObject;
            app.win_type = selectedButton;
        end

        % Selection changed function: ComplixplaneButtonGroup
        function ComplixplaneButtonGroupSelectionChanged(app, event)
            selectedButton = app.ComplixplaneButtonGroup.SelectedObject;
            app.com_plane = selectedButton;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.Compute();
            drawnow;
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = app.PlotDropDown.Value;
            if strcmp(value, 'Original')
                plot(app.UIAxes, app.x)
                xlabel(app.UIAxes, 'Time (s)','FontSize',12)
                ylabel(app.UIAxes, 'Magnitude','FontSize',12)
                title(app.UIAxes,'Original signal')
            elseif strcmp(value, 'Log magnitude')
                plot(app.UIAxes, app.f, app.extracted_frame_log(1:app.half_nfft))
                xlabel(app.UIAxes, 'Frequency (Hz)', 'FontSize',12)
                ylabel(app.UIAxes, 'Magnitude', 'FontSize',12)
                title(app.UIAxes,sprintf('Log magnitude spectrum of frame %d', app.frame_ID))
            elseif strcmp(value, 'Cepstrum')
                plot(app.UIAxes, app.t, app.extracted_frame_cepstrum(1:round(app.frame_length * app.fs)))
                xlabel(app.UIAxes, "Time (s)","FontSize",12)
                ylabel(app.UIAxes, 'Cepstrum','FontSize',12)
                title(app.UIAxes, sprintf('Cepstrum of frame %d', app.frame_ID))
            end
        end

        % Value changed function: SamplesSpinner
        function SamplesSpinnerValueChanged(app, event)
            value = app.SamplesSpinner.Value;
            app.samples = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CepestralAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CepestralAnalysisUIFigure = uifigure('Visible', 'off');
            app.CepestralAnalysisUIFigure.Name = 'Cepestral Analysis';
            app.CepestralAnalysisUIFigure.Icon = fullfile(pathToMLAPP, 'img', 'wave.png');
            app.CepestralAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CepestralAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadfileButton = uibutton(app.LeftPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.SmoothingSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SmoothingSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothingSwitchValueChanged, true);
            app.FilterSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterSwitchValueChanged, true);
            app.SmoothingSwitchLabel = uilabel(app.LeftPanel);
            app.SmoothingSwitchLabel.Text = 'Smoothing';
            app.NormalizingSwitchLabel = uilabel(app.LeftPanel);
            app.NormalizingSwitchLabel.Text = 'Normalizing ';
            app.FilterSwitchLabel = uilabel(app.LeftPanel);
            app.FilterSwitchLabel.Text = 'Filter';
            app.FramesegmentaionLabel = uilabel(app.LeftPanel);
            app.FramesegmentaionLabel.Text = 'Frame segmentaion';
            app.Label = uilabel(app.LeftPanel);
            app.WindowingButtonGroup = uibuttongroup(app.LeftPanel);
            app.WindowingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowingButtonGroupSelectionChanged, true);
            app.WindowingButtonGroup.Title = 'Windowing';
            app.NoneButton = uiradiobutton(app.WindowingButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.HammingButton = uiradiobutton(app.WindowingButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HanningButton = uiradiobutton(app.WindowingButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.BlackmanButton = uiradiobutton(app.WindowingButtonGroup);
            app.BlackmanButton.Text = 'Blackman ';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.FramelengthsSpinnerLabel = uilabel(app.LeftPanel);
            app.FramelengthsSpinnerLabel.Text = 'Frame length (s)';
            app.FramelengthsSpinner = uispinner(app.LeftPanel);
            app.FramelengthsSpinner.Step = 0.001;
            app.FramelengthsSpinner.Limits = [0 1];
            app.FramelengthsSpinner.ValueDisplayFormat = '%5.3f';
            app.FramelengthsSpinner.ValueChangedFcn = createCallbackFcn(app, @FramelengthsSpinnerValueChanged, true);
            app.FrameshiftsSpinnerLabel = uilabel(app.LeftPanel);
            app.FrameshiftsSpinnerLabel.Text = 'Frame shift (s)';
            app.FrameshiftsSpinner = uispinner(app.LeftPanel);
            app.FrameshiftsSpinner.Step = 0.001;
            app.FrameshiftsSpinner.Limits = [0 1];
            app.FrameshiftsSpinner.ValueDisplayFormat = '%5.3f';
            app.FrameshiftsSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameshiftsSpinnerValueChanged, true);
            app.NormalizingSwitch = uiswitch(app.LeftPanel, 'slider');
            app.NormalizingSwitch.ValueChangedFcn = createCallbackFcn(app, @NormalizingSwitchValueChanged, true);
            app.FrameIDSpinnerLabel = uilabel(app.LeftPanel);
            app.FrameIDSpinnerLabel.Text = 'Frame  ID';
            app.FrameIDSpinner = uispinner(app.LeftPanel);
            app.FrameIDSpinner.Limits = [1 Inf];
            app.FrameIDSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameIDSpinnerValueChanged, true);
            app.FrameIDSpinner.Value = 1;
            app.ComplixplaneButtonGroup = uibuttongroup(app.LeftPanel);
            app.ComplixplaneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComplixplaneButtonGroupSelectionChanged, true);
            app.ComplixplaneButtonGroup.Title = 'Complix plane';
            app.RealButton = uiradiobutton(app.ComplixplaneButtonGroup);
            app.RealButton.Text = 'Real';
            app.RealButton.Value = true;
            app.ImaginaryButton = uiradiobutton(app.ComplixplaneButtonGroup);
            app.ImaginaryButton.Text = 'Imaginary';
            app.SamplesSpinnerLabel = uilabel(app.LeftPanel);
            app.SamplesSpinnerLabel.Text = 'Samples';
            app.SamplesSpinner = uispinner(app.LeftPanel);
            app.SamplesSpinner.Limits = [0 Inf];
            app.SamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplesSpinnerValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotDropDownLabel = uilabel(app.RightPanel);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.RightPanel);
            app.PlotDropDown.Items = {'None', 'Original', 'Log magnitude', 'Cepstrum'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = 'None';
            % Show the figure after all components are created
            app.CepestralAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cepstral_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CepestralAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CepestralAnalysisUIFigure)
        end
    end
end